classdef Batch_processing < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BatchprocessingUIFigure      matlab.ui.Figure
        GridLayout13                 matlab.ui.container.GridLayout
        BatchprocessingmoduleLabel   matlab.ui.control.Label
        MethodsPanel                 matlab.ui.container.Panel
        GridLayout11                 matlab.ui.container.GridLayout
        MethodsSettingsButton        matlab.ui.control.StateButton
        SNRmethodPanel               matlab.ui.container.Panel
        GridLayout4                  matlab.ui.container.GridLayout
        InversemodelingCheckBox      matlab.ui.control.CheckBox
        SpectralanalysisCheckBox     matlab.ui.control.CheckBox
        OBSmethodPanel               matlab.ui.container.Panel
        GridLayout5                  matlab.ui.container.GridLayout
        CarrierphasepseudorangeCheckBox  matlab.ui.control.CheckBox
        pseudorangeCheckBox          matlab.ui.control.CheckBox
        CarrierphaseCheckBox         matlab.ui.control.CheckBox
        ParallelnumberDropDown       matlab.ui.control.DropDown
        ParallelnumberDropDownLabel  matlab.ui.control.Label
        StatusLamp                   matlab.ui.control.Lamp
        StatusLampLabel              matlab.ui.control.Label
        OutputpathEditField          matlab.ui.control.EditField
        OutputpathEditFieldLabel     matlab.ui.control.Label
        RUNButton                    matlab.ui.control.StateButton
        chooseButton_4               matlab.ui.control.StateButton
        DatePickerPanel              matlab.ui.container.Panel
        GridLayout3                  matlab.ui.container.GridLayout
        StartdateDatePicker          matlab.ui.control.DatePicker
        StartdateDatePickerLabel     matlab.ui.control.Label
        EnddateDatePicker            matlab.ui.control.DatePicker
        EnddateDatePickerLabel       matlab.ui.control.Label
        ProcessingflowPanel          matlab.ui.container.Panel
        GridLayout12                 matlab.ui.container.GridLayout
        TidalcorrectionOutputFinalfileCheckBox  matlab.ui.control.CheckBox
        InverseReflectionheightCheckBox  matlab.ui.control.CheckBox
        RINEXinfoEXTRCATIONCheckBox  matlab.ui.control.CheckBox
        EphemerisinfoPanel           matlab.ui.container.Panel
        GridLayout10                 matlab.ui.container.GridLayout
        EphemerispathEditField       matlab.ui.control.EditField
        EphemerispathEditFieldLabel  matlab.ui.control.Label
        ACDropDown                   matlab.ui.control.DropDown
        ACDropDownLabel              matlab.ui.control.Label
        chooseButton_2               matlab.ui.control.StateButton
        RinexinfoPanel               matlab.ui.container.Panel
        GridLayout9                  matlab.ui.container.GridLayout
        RinexpathEditField           matlab.ui.control.EditField
        RinexpathEditFieldLabel      matlab.ui.control.Label
        RinexversionDropDown         matlab.ui.control.DropDown
        RinexversionDropDownLabel    matlab.ui.control.Label
        IntervalsEditField           matlab.ui.control.NumericEditField
        IntervalsEditFieldLabel      matlab.ui.control.Label
        chooseButton                 matlab.ui.control.StateButton
        StationinfoPanel             matlab.ui.container.Panel
        GridLayout8                  matlab.ui.container.GridLayout
        StationfileEditField         matlab.ui.control.EditField
        StationfileEditFieldLabel    matlab.ui.control.Label
        StationnameEditField         matlab.ui.control.EditField
        StationnameEditFieldLabel    matlab.ui.control.Label
        chooseButton_3               matlab.ui.control.StateButton
    end


    properties (Access = public)
        methods_settings   struct
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: chooseButton_3
        function chooseButton_3ValueChanged(app, event)
            [fileNames, filepath] = uigetfile('data/station_info/*.*', 'choose station file');

            load(strcat(filepath,'/',fileNames))
            % check
            variables_to_check = {'azi_mask', 'azi_lim', 'elv_lim', 'staxyz', 'sta_lon', 'sta_lat', 'sta_asl', 'tide_range', 'station_belong'};
            missing_variables = {};
            for i = 1:length(variables_to_check)
                if ~exist(variables_to_check{i}, 'var')
                    missing_variables{end+1} = variables_to_check{i};
                end
            end
            if exist('station_name','var')
                app.StationnameEditField.Value = station_name;
            end

            fig = app.BatchprocessingUIFigure;
            if ~isempty(missing_variables)
                err_str = ['Some variables are missing, please check the file! Missing variables: ',missing_variables];
                uialert(fig,err_str,'warning');
                return
            else
                uialert(fig,'Import successfully!','success','Icon','success');
            end

            app.StationfileEditField.Value = strcat(filepath,fileNames);
        end

        % Value changed function: chooseButton
        function chooseButtonValueChanged(app, event)
            dname = uigetdir('data/', 'choose RINEX file');
            app.RinexpathEditField.Value = dname;
            return
        end

        % Value changed function: chooseButton_2
        function chooseButton_2ValueChanged(app, event)
            dname = uigetdir('data/', 'choose Ephemeris file');
            app.EphemerispathEditField.Value = dname;
            return
        end

        % Value changed function: chooseButton_4
        function chooseButton_4ValueChanged(app, event)
            dname = uigetdir(pwd, 'choose Ephemeris file');
            app.OutputpathEditField.Value = dname;
            return
        end

        % Value changed function: RUNButton
        function RUNButtonValueChanged(app, event)
            %             try
            app.StatusLamp.Color = 'g';
            drawnow
            Batch_Callback(app)
            app.StatusLamp.Color = 'r';
            %             catch ME
            %                 app.StatusLamp.Color = 'r';
            %                 fig = app.BatchprocessingUIFigure;
            %                 uialert(fig,[ME.message,newline,'File:',ME.stack(1,:).file,newline,'Name:',...
            %                     ME.stack(1,:).name,newline,'Line:',num2str(ME.stack(1,:).line)],'warning')
            %                 return
            %             end
        end

        % Value changed function: MethodsSettingsButton
        function MethodsSettingsButtonValueChanged(app, event)
            codeFilePath = 'genMethodsSettings.m';

            edit(codeFilePath);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.BatchprocessingUIFigure = uifigure('Visible', 'off');
            app.BatchprocessingUIFigure.Name = 'Batch processing';
            app.BatchprocessingUIFigure.Icon = 'Batch.png';
            app.GridLayout13 = uigridlayout(app.BatchprocessingUIFigure);
            app.StationinfoPanel = uipanel(app.GridLayout13);
            app.StationinfoPanel.Title = 'Station info';
            app.GridLayout8 = uigridlayout(app.StationinfoPanel);
            app.chooseButton_3 = uibutton(app.GridLayout8, 'state');
            app.chooseButton_3.ValueChangedFcn = createCallbackFcn(app, @chooseButton_3ValueChanged, true);
            app.chooseButton_3.Icon = fullfile(pathToMLAPP, 'icon', '8664907_folder_open_document_icon.png');
            app.chooseButton_3.Text = 'choose';
            app.StationnameEditFieldLabel = uilabel(app.GridLayout8);
            app.StationnameEditFieldLabel.Text = 'Station name';
            app.StationnameEditField = uieditfield(app.GridLayout8, 'text');
            app.StationfileEditFieldLabel = uilabel(app.GridLayout8);
            app.StationfileEditFieldLabel.Text = 'Station file';
            app.StationfileEditField = uieditfield(app.GridLayout8, 'text');
            app.RinexinfoPanel = uipanel(app.GridLayout13);
            app.RinexinfoPanel.Title = 'Rinex info';
            app.GridLayout9 = uigridlayout(app.RinexinfoPanel);
            app.chooseButton = uibutton(app.GridLayout9, 'state');
            app.chooseButton.ValueChangedFcn = createCallbackFcn(app, @chooseButtonValueChanged, true);
            app.chooseButton.Icon = fullfile(pathToMLAPP, 'icon', '8664907_folder_open_document_icon.png');
            app.chooseButton.Text = 'choose';
            app.IntervalsEditFieldLabel = uilabel(app.GridLayout9);
            app.IntervalsEditFieldLabel.Text = 'Interval(s)';
            app.IntervalsEditField = uieditfield(app.GridLayout9, 'numeric');
            app.IntervalsEditField.Value = 15;
            app.RinexversionDropDownLabel = uilabel(app.GridLayout9);
            app.RinexversionDropDownLabel.Text = 'Rinex version';
            app.RinexversionDropDown = uidropdown(app.GridLayout9);
            app.RinexversionDropDown.Items = {'2', '3'};
            app.RinexversionDropDown.Value = '3';
            app.RinexpathEditFieldLabel = uilabel(app.GridLayout9);
            app.RinexpathEditFieldLabel.Text = 'Rinex path';
            app.RinexpathEditField = uieditfield(app.GridLayout9, 'text');
            app.EphemerisinfoPanel = uipanel(app.GridLayout13);
            app.EphemerisinfoPanel.Title = 'Ephemeris info';
            app.GridLayout10 = uigridlayout(app.EphemerisinfoPanel);
            app.chooseButton_2 = uibutton(app.GridLayout10, 'state');
            app.chooseButton_2.ValueChangedFcn = createCallbackFcn(app, @chooseButton_2ValueChanged, true);
            app.chooseButton_2.Icon = fullfile(pathToMLAPP, 'icon', '8664907_folder_open_document_icon.png');
            app.chooseButton_2.Text = 'choose';
            app.ACDropDownLabel = uilabel(app.GridLayout10);
            app.ACDropDownLabel.Text = 'AC';
            app.ACDropDown = uidropdown(app.GridLayout10);
            app.ACDropDown.Items = {'CODE', 'GFZ'};
            app.ACDropDown.Value = 'CODE';
            app.EphemerispathEditFieldLabel = uilabel(app.GridLayout10);
            app.EphemerispathEditFieldLabel.Text = 'Ephemeris path';
            app.EphemerispathEditField = uieditfield(app.GridLayout10, 'text');
            app.ProcessingflowPanel = uipanel(app.GridLayout13);
            app.ProcessingflowPanel.Title = 'Processing flow';
            app.GridLayout12 = uigridlayout(app.ProcessingflowPanel);
            app.RINEXinfoEXTRCATIONCheckBox = uicheckbox(app.GridLayout12);
            app.RINEXinfoEXTRCATIONCheckBox.Text = 'RINEX info EXTRCATION';
            app.RINEXinfoEXTRCATIONCheckBox.Value = true;
            app.InverseReflectionheightCheckBox = uicheckbox(app.GridLayout12);
            app.InverseReflectionheightCheckBox.Text = 'Inverse Reflection height';
            app.InverseReflectionheightCheckBox.Value = true;
            app.TidalcorrectionOutputFinalfileCheckBox = uicheckbox(app.GridLayout12);
            app.TidalcorrectionOutputFinalfileCheckBox.Text = 'Tidal correction & Output Final file';
            app.TidalcorrectionOutputFinalfileCheckBox.Value = true;
            app.DatePickerPanel = uipanel(app.GridLayout13);
            app.DatePickerPanel.Title = 'Date Picker';
            app.GridLayout3 = uigridlayout(app.DatePickerPanel);
            app.EnddateDatePickerLabel = uilabel(app.GridLayout3);
            app.EnddateDatePickerLabel.Text = 'End date';
            app.EnddateDatePicker = uidatepicker(app.GridLayout3);
            app.EnddateDatePicker.DisplayFormat = 'uuuu-MM-dd';
            app.EnddateDatePicker.Value = datetime([2023 1 2]);
            app.StartdateDatePickerLabel = uilabel(app.GridLayout3);
            app.StartdateDatePickerLabel.Text = 'Start date';
            app.StartdateDatePicker = uidatepicker(app.GridLayout3);
            app.StartdateDatePicker.DisplayFormat = 'uuuu-MM-dd';
            app.StartdateDatePicker.Value = datetime([2023 1 1]);
            app.chooseButton_4 = uibutton(app.GridLayout13, 'state');
            app.chooseButton_4.ValueChangedFcn = createCallbackFcn(app, @chooseButton_4ValueChanged, true);
            app.chooseButton_4.Icon = fullfile(pathToMLAPP, 'icon', '8664907_folder_open_document_icon.png');
            app.chooseButton_4.Text = 'choose';
            app.RUNButton = uibutton(app.GridLayout13, 'state');
            app.RUNButton.ValueChangedFcn = createCallbackFcn(app, @RUNButtonValueChanged, true);
            app.RUNButton.Icon = fullfile(pathToMLAPP, 'icon', '运行.png');
            app.RUNButton.Text = 'RUN';
            app.OutputpathEditFieldLabel = uilabel(app.GridLayout13);
            app.OutputpathEditFieldLabel.Text = 'Output path';
            app.OutputpathEditField = uieditfield(app.GridLayout13, 'text');
            app.StatusLampLabel = uilabel(app.GridLayout13);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.GridLayout13);
            app.ParallelnumberDropDownLabel = uilabel(app.GridLayout13);
            app.ParallelnumberDropDownLabel.Text = 'Parallel number';
            app.ParallelnumberDropDown = uidropdown(app.GridLayout13);
            app.ParallelnumberDropDown.Items = {'None', '2', '4', '6', '8'};
            app.ParallelnumberDropDown.Value = 'None';
            app.MethodsPanel = uipanel(app.GridLayout13);
            app.MethodsPanel.Title = 'Methods';
            app.GridLayout11 = uigridlayout(app.MethodsPanel);
            app.OBSmethodPanel = uipanel(app.GridLayout11);
            app.OBSmethodPanel.Title = 'OBS method';
            app.GridLayout5 = uigridlayout(app.OBSmethodPanel);
            app.CarrierphaseCheckBox = uicheckbox(app.GridLayout5);
            app.CarrierphaseCheckBox.Text = 'Carrier phase';
            app.CarrierphaseCheckBox.Value = true;
            app.pseudorangeCheckBox = uicheckbox(app.GridLayout5);
            app.pseudorangeCheckBox.Text = 'pseudorange';
            app.pseudorangeCheckBox.Value = true;
            app.CarrierphasepseudorangeCheckBox = uicheckbox(app.GridLayout5);
            app.CarrierphasepseudorangeCheckBox.Text = 'Carrier phase & pseudorange';
            app.CarrierphasepseudorangeCheckBox.Value = true;
            app.SNRmethodPanel = uipanel(app.GridLayout11);
            app.SNRmethodPanel.Title = 'SNR method';
            app.GridLayout4 = uigridlayout(app.SNRmethodPanel);
            app.SpectralanalysisCheckBox = uicheckbox(app.GridLayout4);
            app.SpectralanalysisCheckBox.Text = 'Spectral analysis';
            app.SpectralanalysisCheckBox.Value = true;
            app.InversemodelingCheckBox = uicheckbox(app.GridLayout4);
            app.InversemodelingCheckBox.Text = 'Inverse modeling';
            app.InversemodelingCheckBox.Value = true;
            app.MethodsSettingsButton = uibutton(app.GridLayout11, 'state');
            app.MethodsSettingsButton.ValueChangedFcn = createCallbackFcn(app, @MethodsSettingsButtonValueChanged, true);
            app.MethodsSettingsButton.Text = 'Methods Settings';
            app.BatchprocessingmoduleLabel = uilabel(app.GridLayout13);
            app.BatchprocessingmoduleLabel.Text = 'Batch processing module';
            % Show the figure after all components are created
            app.BatchprocessingUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Batch_processing

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BatchprocessingUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BatchprocessingUIFigure)
        end
    end
end