classdef Inversion_based_on_pseudorange_information < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout23                    matlab.ui.container.GridLayout
        PseudorangecombinationinversionLabel  matlab.ui.control.Label
        TabGroup11                      matlab.ui.container.TabGroup
        SettingsTab                     matlab.ui.container.Tab
        GridLayout13                    matlab.ui.container.GridLayout
        TabGroup7                       matlab.ui.container.TabGroup
        StationTab                      matlab.ui.container.Tab
        GridLayout14                    matlab.ui.container.GridLayout
        stationmap                      matlab.ui.container.Panel
        StationinformationPanel         matlab.ui.container.Panel
        GridLayout15                    matlab.ui.container.GridLayout
        ImportSettingsButton            matlab.ui.control.StateButton
        belong                          matlab.ui.control.EditField
        Label_32                        matlab.ui.control.Label
        station                         matlab.ui.control.EditField
        Label_31                        matlab.ui.control.Label
        Image8                          matlab.ui.control.Image
        SettingsofelevationandazimuthPanel  matlab.ui.container.Panel
        GridLayout17                    matlab.ui.container.GridLayout
        EditField_3Label_3              matlab.ui.control.Label
        azimask_max                     matlab.ui.control.EditField
        azimask_min                     matlab.ui.control.EditField
        Label_33                        matlab.ui.control.Label
        azi_max                         matlab.ui.control.EditField
        EditField_3Label_2              matlab.ui.control.Label
        azi_min                         matlab.ui.control.EditField
        AzimuthminLabel                 matlab.ui.control.Label
        elv_max                         matlab.ui.control.EditField
        maxLabel                        matlab.ui.control.Label
        elv_min                         matlab.ui.control.EditField
        ElevationminLabel               matlab.ui.control.Label
        LocationinfoPanel               matlab.ui.container.Panel
        GridLayout16                    matlab.ui.container.GridLayout
        basemap                         matlab.ui.control.DropDown
        DropDown_2Label                 matlab.ui.control.Label
        HEditField                      matlab.ui.control.EditField
        HEditFieldLabel                 matlab.ui.control.Label
        load_station_info               matlab.ui.control.Button
        SpacerectangularcoordinatesystemmLabel  matlab.ui.control.Label
        GeodeticcoordinatesystemmLabel  matlab.ui.control.Label
        get_station_info                matlab.ui.control.Button
        lat                             matlab.ui.control.EditField
        LatLabel                        matlab.ui.control.Label
        lon                             matlab.ui.control.EditField
        LonLabel                        matlab.ui.control.Label
        Z                               matlab.ui.control.NumericEditField
        mZLabel                         matlab.ui.control.Label
        Y                               matlab.ui.control.NumericEditField
        mYLabel                         matlab.ui.control.Label
        X                               matlab.ui.control.NumericEditField
        XLabel                          matlab.ui.control.Label
        FilesTab                        matlab.ui.container.Tab
        GridLayout18                    matlab.ui.container.GridLayout
        SaveLoadSettingsButton          matlab.ui.control.Button
        Image9                          matlab.ui.control.Image
        TimsPanel                       matlab.ui.container.Panel
        GridLayout19                    matlab.ui.container.GridLayout
        Image_2                         matlab.ui.control.Image
        Image                           matlab.ui.control.Image
        endtime                         matlab.ui.control.DatePicker
        DatePicker_2Label               matlab.ui.control.Label
        starttime                       matlab.ui.control.DatePicker
        StartdateLabel                  matlab.ui.control.Label
        EphemerisFilesPanel             matlab.ui.container.Panel
        GridLayout21                    matlab.ui.container.GridLayout
        sp3filename                     matlab.ui.control.TextArea
        Label_37                        matlab.ui.control.Label
        sp3_type                        matlab.ui.control.DropDown
        Label_36                        matlab.ui.control.Label
        Image3                          matlab.ui.control.Image
        sp3filenumber                   matlab.ui.control.Label
        path_sp3                        matlab.ui.control.Button
        sp3                             matlab.ui.control.EditField
        Pathofsp3Label                  matlab.ui.control.Label
        getsp3                          matlab.ui.control.Button
        RINEXFilesPanel                 matlab.ui.container.Panel
        GridLayout20                    matlab.ui.container.GridLayout
        dt                              matlab.ui.control.EditField
        Label_35                        matlab.ui.control.Label
        obsfilename                     matlab.ui.control.TextArea
        Label_34                        matlab.ui.control.Label
        rinex_version                   matlab.ui.control.DropDown
        rinexversionLabel               matlab.ui.control.Label
        Image2                          matlab.ui.control.Image
        obsfilenumber                   matlab.ui.control.Label
        path_o                          matlab.ui.control.Button
        observation                     matlab.ui.control.EditField
        PathofrinexLabel                matlab.ui.control.Label
        WaterlevelinversionTab          matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        ispic                           matlab.ui.control.Switch
        Label                           matlab.ui.control.Label
        InverseButton                   matlab.ui.control.StateButton
        combination_type                matlab.ui.control.DropDown
        Label_4                         matlab.ui.control.Label
        rf_range                        matlab.ui.control.NumericEditField
        RangemLabel                     matlab.ui.control.Label
        antenna_height                  matlab.ui.control.NumericEditField
        EditField_3Label                matlab.ui.control.Label
        path_cpi_m                      matlab.ui.control.EditField
        FinalfilePathLabel              matlab.ui.control.Label
        chooseButton_2                  matlab.ui.control.Button
        path_cpi                        matlab.ui.control.EditField
        PseudorangepathLabel            matlab.ui.control.Label
        chooseButton                    matlab.ui.control.Button
        PseudorangeextractionButton     matlab.ui.control.StateButton
        car_tab                         matlab.ui.control.Table
        Panel                           matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        p3                              matlab.ui.control.DropDown
        DropDown_13Label                matlab.ui.control.Label
        s3                              matlab.ui.control.DropDown
        SystemLabel_2                   matlab.ui.control.Label
        Band3Label                      matlab.ui.control.Label
        p2                              matlab.ui.control.DropDown
        DropDown_12Label                matlab.ui.control.Label
        s2                              matlab.ui.control.DropDown
        DropDown_9Label                 matlab.ui.control.Label
        Band2Label                      matlab.ui.control.Label
        p1                              matlab.ui.control.DropDown
        FrequencyLabel                  matlab.ui.control.Label
        Band1Label                      matlab.ui.control.Label
        s1                              matlab.ui.control.DropDown
        SystemLabel                     matlab.ui.control.Label
        psd                             matlab.ui.control.UIAxes
        cmps                            matlab.ui.control.UIAxes
        TidallevelverificationTab       matlab.ui.container.Tab
        GridLayout22                    matlab.ui.container.GridLayout
        DrawingoptionsLabel             matlab.ui.control.Label
        DH                              matlab.ui.control.CheckBox
        cor                             matlab.ui.control.CheckBox
        tide_path                       matlab.ui.control.EditField
        Label_11                        matlab.ui.control.Label
        result_scatter                  matlab.ui.control.StateButton
        chooseButton_3                  matlab.ui.control.Button
        results                         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: chooseButton_2
        function chooseButton_2Pushed(app, event)
            dname = uigetdir(pwd);
            app.path_cpi_m.Value = dname;
            return
        end

        % Callback function
        function Button_7Pushed(app, event)
            dname = uigetdir(pwd);
            app.path_cpi_m_2.Value = dname;
            return
        end

        % Callback function
        function Button_5Pushed(app, event)
            if exist("Exchange_information\station_setting.mat","file")

                global time sp3_type rinex_version station_l station_xyz elv azimask azi station_belong station_name sp3_path obs_path dt
                load("Exchange_information\station_setting.mat")

                fig = app.UIFigure;
                uialert(fig,'测站信息加载成功','success','Icon','success');
            else
                fig = app.UIFigure;
                uialert(fig,'请先设置测站信息！','warning');
            end
        end

        % Button pushed function: chooseButton
        function chooseButtonPushed(app, event)
            dname = uigetdir(pwd);
            app.path_cpi.Value = dname;
            return
        end

        % Value changed function: PseudorangeextractionButton
        function PseudorangeextractionButtonValueChanged(app, event)
            global Operation_settings

            all_days = daysact(Operation_settings.time(1),Operation_settings.time(2))+1;
            ok_days = 0;
            fig = app.UIFigure;
            progress_day = strcat('(',num2str(ok_days),'/',num2str(all_days),')');
            d = uiprogressdlg(fig,'Title',strcat('Extraction progress ',progress_day),'Message','extracting……','Cancelable','on');
            drawnow
            set(0,'DefaultFigureVisible', 'off');
            type = "Pseudorange";
            [carrier_data] = load_carrier(app, d, type, app.path_cpi.Value);
            % display result
            app.s1.Items = fieldnames(carrier_data);
            app.s2.Items = fieldnames(carrier_data);
            app.s3.Items = fieldnames(carrier_data);
            struct = carrier_data.GPS;
            n = struct.Properties.VariableNames;
            app.p1.Items = n(5:end);
            app.p2.Items = n(5:end);
            app.p3.Items = n(5:end);
            return
        end

        % Value changed function: s1
        function s1ValueChanged(app, event)
            global Operation_settings
            sys1 = app.s1.Value;
            file_name = strcat(app.path_cpi.Value,'\',Operation_settings.station_name,num2str(datenum(Operation_settings.time(1))),'.mat');
            load(file_name)

            if string(sys1) == "GPS"
                struct = pseudorange_data.GPS;
            elseif string(sys1) == "GLONASS"
                struct = pseudorange_data.GLONASS;
            elseif string(sys1) == "GALILEO"
                struct = pseudorange_data.GALILEO;
            elseif string(sys1) == "BDS"
                struct = pseudorange_data.BDS;
            end
            n = struct.Properties.VariableNames;
            app.p1.Items = n(5:end);
        end

        % Value changed function: s2
        function s2ValueChanged(app, event)
            global Operation_settings
            sys2 = app.s2.Value;
            file_name = strcat(app.path_cpi.Value,'\',Operation_settings.station_name,num2str(datenum(Operation_settings.time(1))),'.mat');
            load(file_name)

            if string(sys2) == "GPS"
                struct = pseudorange_data.GPS;
            elseif string(sys2) == "GLONASS"
                struct = pseudorange_data.GLONASS;
            elseif string(sys2) == "GALILEO"
                struct = pseudorange_data.GALILEO;
            elseif string(sys2) == "BDS"
                struct = pseudorange_data.BDS;
            end
            n = struct.Properties.VariableNames;
            app.p2.Items = n(5:end);
        end

        % Value changed function: s3
        function s3ValueChanged(app, event)
            global Operation_settings
            sys3 = app.s3.Value;
            file_name = strcat(app.path_cpi.Value,'\',Operation_settings.station_name,num2str(datenum(Operation_settings.time(1))),'.mat');
            load(file_name)

            if string(sys3) == "GPS"
                struct = pseudorange_data.GPS;
            elseif string(sys3) == "GLONASS"
                struct = pseudorange_data.GLONASS;
            elseif string(sys3) == "GALILEO"
                struct = pseudorange_data.GALILEO;
            elseif string(sys3) == "BDS"
                struct = pseudorange_data.BDS;
            end
            n = struct.Properties.VariableNames;
            app.p3.Items = n(5:end);
        end

        % Button pushed function: chooseButton_3
        function chooseButton_3Pushed(app, event)
            [fileNames, filepath] = uigetfile('tide_data/*.*', 'Select the tide file');
            app.tide_path.Value = strcat(filepath,'/',fileNames);
            return
        end

        % Value changed function: result_scatter
        function result_scatterValueChanged(app, event)
            PlotFinalFileCallback(app,"pseudorange")
        end

        % Callback function
        function tide_plotButtonPushed(app, event)
            
        end

        % Value changed function: InverseButton
        function InverseButtonValueChanged(app, event)
            CombinationInverseCallback(app,"pseudorange")
        end

        % Value changed function: combination_type
        function combination_typeValueChanged(app, event)
            value = app.combination_type.Value;
            if  string(value) == "Dual frequency combination"
                app.s2.Visible = "on";
                app.p2.Visible = "on";
                app.s3.Visible = "off";
                app.p3.Visible = "off";
            elseif string(value) == "Three-frequency combination"
                app.s2.Visible = "on";
                app.p2.Visible = "on";
                app.s3.Visible = "on";
                app.p3.Visible = "on";
            end
        end

        % Callback function
        function load_station_infoButtonPushed(app, event)
            [fileNames, filepath] = uigetfile(strcat(pwd,'\data\station_info\*.m*'),'Select station information');
            run(strcat(filepath,'/',fileNames))
            app.elv_max.Value = num2str(elv_lim(2));
            app.elv_min.Value = num2str(elv_lim(1));
            app.azi_min.Value = num2str(azi_lim(1));
            app.azi_max.Value = num2str(azi_lim(2));
            app.azimask_max.Value = num2str(azi_mask(2));
            app.azimask_min.Value = num2str(azi_mask(1));
            app.lat.Value = num2str(sat_lat);
            app.lon.Value = num2str(sat_lon);

            app.X.Value = staxyz(1);
            app.Y.Value = staxyz(2);
            app.Z.Value = staxyz(3);
        end

        % Callback function
        function get_station_infoButtonPushed(app, event)
            addpath("part1\")
            fig = app.UIFigure;
            uiprogressdlg(fig,'Message','绘制中，请稍后……','Cancelable','on');
            %            [staxyz,plat,plon,azi_low,azi_high,azi_mask,elv_low,elv_high] = getinfo(app,app.station.Value);


            % station map
            plon = str2double(app.lon.Value);
            plat = str2double(app.lat.Value);

            h = geoaxes(app.stationmap);
            geoscatter(h, plat, plon, 'filled', 'red');
            geolimits(h,[plat-0.0025 plat+0.0025],[plon-0.00125 plon+0.00125]);
            geobasemap(h,app.basemap.Value)
            legend(h,'station')
        end

        % Callback function
        function path_oButtonPushed(app, event)
            [fileNames, filepath] = uigetfile('data/*.*', '选择观测文件', 'MultiSelect', 'on');

            if isequal(fileNames, 0)
                return;
            end
            fileNames = cellstr(fileNames);
            fig = app.UIFigure;

            fileCount = 0;
            fileCount = fileCount + length(fileNames);
            app.obsfilenumber.Text = sprintf('已选择：%d个文件', fileCount);
            app.observation.Value = filepath;
            date_is = 1;
            if fileCount == 1
                % filenames
                app.obsfilename.Value = fileNames;
                % rinex version
                fileNames_char = cell2mat(fileNames);
                fileID = fopen(strcat(filepath,'\',fileNames_char), 'r');
                data = textscan(fileID, '%s', 'Delimiter', '\n');
                fclose(fileID);
                for i = 1:length(data{1})
                    line = data{1}{i};
                    if endsWith(line, 'RINEX VERSION / TYPE')
                        ver = line(1:9);
                    end
                end
                if str2double(ver) >= 3
                    app.rinex_version.Value = 'rinex 3';
                elseif str2double(ver) >= 2 && str2double(ver) < 3
                    app.rinex_version.Value = 'rinex 2';
                else
                    uialert(fig,{'无法自动读取rinex版本','请手动选择版本与日期！'},'Warning','Icon','warning');
                    date_is = 0;
                end
                % datetime
                if date_is == 1
                    if app.rinex_version.Value == 'rinex 3'
                        doy_str = fileNames_char(17:19);
                        year_str = fileNames_char(13:16);
                        start_date = datetime(str2double(year_str),1,str2double(doy_str));
                        end_date = start_date;
                    elseif app.rinex_version.Value == 'rinex 2'
                        doy_str = fileNames_char(5:7);
                        year_str = strcat('20',fileNames_char(10:11));
                        start_date = datetime(str2double(year_str),1,str2double(doy_str));
                        end_date = start_date;
                    end
                    app.starttime.Value = start_date;
                    app.endtime.Value = end_date;
                end
            else % multiple files
                app. obsfilename.Value = fileNames';
                % rinex version
                fileNames_char = cell2mat(fileNames(1));
                fileID = fopen(strcat(filepath,'\',fileNames_char), 'r');
                data = textscan(fileID, '%s', 'Delimiter', '\n');
                fclose(fileID);
                for i = 1:length(data{1})
                    line = data{1}{i};
                    if endsWith(line, 'RINEX VERSION / TYPE')
                        ver = line(1:9);
                    end
                end
                if str2double(ver) >= 3
                    app.rinex_version.Value = 'rinex 3';
                elseif str2double(ver) >= 2 && str2double(ver) < 3
                    app.rinex_version.Value = 'rinex 2';
                else
                    uialert(fig,{'无法自动读取rinex版本','请手动选择版本与日期！'},'Warning','Icon','warning');
                    date_is = 0;
                end
                % datetime
                if date_is == 1
                    fileNames_char = cell2mat(fileNames');
                    for days = 1:fileCount
                        if app.rinex_version.Value == 'rinex 3'
                            fileNames_day = fileNames_char(days,:);
                            doy_str = fileNames_day(17:19);
                            year_str = fileNames_day(13:16);
                            date(days) = datetime(str2double(year_str),1,str2double(doy_str));
                        elseif app.rinex_version.Value == 'rinex 2'
                            fileNames_day = fileNames_char(days,:);
                            doy_str = fileNames_day(5:7);
                            year_str = strcat('20',fileNames_day(10:11));
                            date(days) = datetime(str2double(year_str),1,str2double(doy_str));

                        end
                    end
                    date = sort(date,'ascend');
                    start_date = date(1);
                    end_date = date(end);
                    app.starttime.Value = start_date;
                    app.endtime.Value = end_date;
                end
            end
        end

        % Callback function
        function path_sp3ButtonPushed(app, event)
            [fileNames, filepath] = uigetfile('data/sp3/*.*', '选择星历文件', 'MultiSelect', 'on');

            if isequal(fileNames, 0)
                return;
            end

            fileNames = cellstr(fileNames);
            fileCount = 0;
            fileCount = fileCount + length(fileNames);
            app.sp3filenumber.Text = sprintf('已选择：%d个文件', fileCount);
            app.sp3.Value = filepath;
            if fileCount == 1
                app.sp3filename.Value = fileNames;

                filenames_char = cell2mat(fileNames);
                app.sp3_type.Value = filenames_char(1:3);
            else
                app. sp3filename.Value = fileNames';
                filenames_char = cell2mat(fileNames(1));
                app.sp3_type.Value = filenames_char(1:3);
            end
        end

        % Callback function
        function getsp3ButtonPushed(app, event)
            addpath('part1\')
            start_date = app.starttime.Value;
            end_date = app.endtime.Value;
            sp3_option = app.sp3_type.Value;
            save_path_sp3 = app.sp3.Value;
            get_sp3(app,start_date,end_date,sp3_option,save_path_sp3)
        end

        % Callback function
        function Button_7Pushed2(app, event)

        end

        % Value changed function: ImportSettingsButton
        function ImportSettingsButtonValueChanged(app, event)
            ImportSettingCallback(app)
        end

        % Button pushed function: load_station_info
        function load_station_infoButtonPushed2(app, event)
            stationInfoCallback(app)
        end

        % Button pushed function: get_station_info
        function get_station_infoButtonPushed2(app, event)
            GeoStationCallback(app)
        end

        % Button pushed function: path_o
        function path_oButtonPushed2(app, event)
            ObsChooseCallback(app)
        end

        % Button pushed function: path_sp3
        function path_sp3ButtonPushed2(app, event)
            EphChooseCallback(app)
        end

        % Button pushed function: getsp3
        function getsp3ButtonPushed2(app, event)
            GetEphCallback(app)
        end

        % Button pushed function: SaveLoadSettingsButton
        function SaveLoadSettingsButtonPushed(app, event)
            SaveSettingCallback(app)
        end

        % Drop down opening function: s1
        function s1DropDownOpening(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout23 = uigridlayout(app.UIFigure);
            app.TabGroup11 = uitabgroup(app.GridLayout23);
            app.SettingsTab = uitab(app.TabGroup11);
            app.SettingsTab.Title = 'Settings';
            app.GridLayout13 = uigridlayout(app.SettingsTab);
            app.TabGroup7 = uitabgroup(app.GridLayout13);
            app.StationTab = uitab(app.TabGroup7);
            app.StationTab.Title = 'Station';
            app.GridLayout14 = uigridlayout(app.StationTab);
            app.StationinformationPanel = uipanel(app.GridLayout14);
            app.StationinformationPanel.Title = 'Station information';
            app.GridLayout15 = uigridlayout(app.StationinformationPanel);
            app.LocationinfoPanel = uipanel(app.GridLayout15);
            app.LocationinfoPanel.Title = 'Location info';
            app.GridLayout16 = uigridlayout(app.LocationinfoPanel);
            app.XLabel = uilabel(app.GridLayout16);
            app.XLabel.Text = 'X';
            app.X = uieditfield(app.GridLayout16, 'numeric');
            app.X.ValueDisplayFormat = '%.4f';
            app.X.Value = -2168281.1141;
            app.mYLabel = uilabel(app.GridLayout16);
            app.mYLabel.Text = 'Y';
            app.Y = uieditfield(app.GridLayout16, 'numeric');
            app.Y.ValueDisplayFormat = '%.4f';
            app.Y.Value = 4387988.0809;
            app.mZLabel = uilabel(app.GridLayout16);
            app.mZLabel.Text = 'Z';
            app.Z = uieditfield(app.GridLayout16, 'numeric');
            app.Z.ValueDisplayFormat = '%.4f';
            app.Z.Value = 4076014.3417;
            app.LonLabel = uilabel(app.GridLayout16);
            app.LonLabel.Text = 'Lon';
            app.lon = uieditfield(app.GridLayout16, 'text');
            app.lon.Value = '116.2958266369';
            app.LatLabel = uilabel(app.GridLayout16);
            app.LatLabel.Text = 'Lat';
            app.lat = uieditfield(app.GridLayout16, 'text');
            app.lat.Value = '39.9761707123 ';
            app.get_station_info = uibutton(app.GridLayout16, 'push');
            app.get_station_info.ButtonPushedFcn = createCallbackFcn(app, @get_station_infoButtonPushed2, true);
            app.get_station_info.Icon = fullfile(pathToMLAPP, 'icon', '出行导航.png');
            app.get_station_info.Text = 'Map the geographical location';
            app.GeodeticcoordinatesystemmLabel = uilabel(app.GridLayout16);
            app.GeodeticcoordinatesystemmLabel.Text = 'Geodetic coordinate system (m)';
            app.SpacerectangularcoordinatesystemmLabel = uilabel(app.GridLayout16);
            app.SpacerectangularcoordinatesystemmLabel.Text = 'Space rectangular coordinate system (m)';
            app.load_station_info = uibutton(app.GridLayout16, 'push');
            app.load_station_info.ButtonPushedFcn = createCallbackFcn(app, @load_station_infoButtonPushed2, true);
            app.load_station_info.Icon = fullfile(pathToMLAPP, 'icon', '导入.png');
            app.load_station_info.Text = 'Import';
            app.HEditFieldLabel = uilabel(app.GridLayout16);
            app.HEditFieldLabel.Text = 'H';
            app.HEditField = uieditfield(app.GridLayout16, 'text');
            app.HEditField.Value = '87.1290197028';
            app.DropDown_2Label = uilabel(app.GridLayout16);
            app.DropDown_2Label.Text = 'Base map type';
            app.basemap = uidropdown(app.GridLayout16);
            app.basemap.Items = {'satellite', 'topographic', 'streets-dark', 'streets'};
            app.basemap.Value = 'satellite';
            app.SettingsofelevationandazimuthPanel = uipanel(app.GridLayout15);
            app.SettingsofelevationandazimuthPanel.Title = ' Settings of elevation and azimuth';
            app.GridLayout17 = uigridlayout(app.SettingsofelevationandazimuthPanel);
            app.ElevationminLabel = uilabel(app.GridLayout17);
            app.ElevationminLabel.Text = 'Elevation: min(°)';
            app.elv_min = uieditfield(app.GridLayout17, 'text');
            app.elv_min.Value = '5';
            app.maxLabel = uilabel(app.GridLayout17);
            app.maxLabel.Text = 'max(°)';
            app.elv_max = uieditfield(app.GridLayout17, 'text');
            app.elv_max.Value = '30';
            app.AzimuthminLabel = uilabel(app.GridLayout17);
            app.AzimuthminLabel.Text = 'Azimuth: min(°)';
            app.azi_min = uieditfield(app.GridLayout17, 'text');
            app.azi_min.Value = '0';
            app.EditField_3Label_2 = uilabel(app.GridLayout17);
            app.EditField_3Label_2.Text = 'max(°)';
            app.azi_max = uieditfield(app.GridLayout17, 'text');
            app.azi_max.Value = '360';
            app.Label_33 = uilabel(app.GridLayout17);
            app.Label_33.Text = 'Azimuth mask(°)';
            app.azimask_min = uieditfield(app.GridLayout17, 'text');
            app.azimask_min.Value = '105';
            app.azimask_max = uieditfield(app.GridLayout17, 'text');
            app.azimask_max.Value = '300';
            app.EditField_3Label_3 = uilabel(app.GridLayout17);
            app.EditField_3Label_3.Text = 'to';
            app.Image8 = uiimage(app.GridLayout15);
            app.Image8.ImageSource = fullfile(pathToMLAPP, 'icon', 'satellite-tower.2.png');
            app.Label_31 = uilabel(app.GridLayout15);
            app.Label_31.Text = 'Name';
            app.station = uieditfield(app.GridLayout15, 'text');
            app.station.Value = 'at01';
            app.Label_32 = uilabel(app.GridLayout15);
            app.Label_32.Text = 'Belong';
            app.belong = uieditfield(app.GridLayout15, 'text');
            app.belong.Value = 'USA';
            app.ImportSettingsButton = uibutton(app.GridLayout15, 'state');
            app.ImportSettingsButton.ValueChangedFcn = createCallbackFcn(app, @ImportSettingsButtonValueChanged, true);
            app.ImportSettingsButton.Icon = fullfile(pathToMLAPP, 'icon', '导入.png');
            app.ImportSettingsButton.Text = 'Import Settings';
            app.stationmap = uipanel(app.GridLayout14);
            app.stationmap.Title = 'Geographical location diagram of the station';
            app.FilesTab = uitab(app.TabGroup7);
            app.FilesTab.Title = 'Files';
            app.GridLayout18 = uigridlayout(app.FilesTab);
            app.RINEXFilesPanel = uipanel(app.GridLayout18);
            app.RINEXFilesPanel.Title = 'RINEX Files';
            app.GridLayout20 = uigridlayout(app.RINEXFilesPanel);
            app.PathofrinexLabel = uilabel(app.GridLayout20);
            app.PathofrinexLabel.Text = 'Path of rinex: ';
            app.observation = uieditfield(app.GridLayout20, 'text');
            app.observation.Value = 'E:\桌面\GNSS_IR水位反演软件\data\hkqt\obs';
            app.path_o = uibutton(app.GridLayout20, 'push');
            app.path_o.ButtonPushedFcn = createCallbackFcn(app, @path_oButtonPushed2, true);
            app.path_o.Icon = fullfile(pathToMLAPP, 'icon', '8664907_folder_open_document_icon.png');
            app.path_o.Text = 'choose';
            app.obsfilenumber = uilabel(app.GridLayout20);
            app.obsfilenumber.Text = 'Selected: 0 files';
            app.Image2 = uiimage(app.GridLayout20);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'icon', '卫星(1).png');
            app.rinexversionLabel = uilabel(app.GridLayout20);
            app.rinexversionLabel.Text = 'rinex version';
            app.rinex_version = uidropdown(app.GridLayout20);
            app.rinex_version.Items = {'rinex 2', 'rinex 3'};
            app.rinex_version.Value = 'rinex 3';
            app.Label_34 = uilabel(app.GridLayout20);
            app.Label_34.Text = 'RIENX Files List';
            app.obsfilename = uitextarea(app.GridLayout20);
            app.Label_35 = uilabel(app.GridLayout20);
            app.Label_35.Text = 'Interval';
            app.dt = uieditfield(app.GridLayout20, 'text');
            app.dt.Value = '30';
            app.EphemerisFilesPanel = uipanel(app.GridLayout18);
            app.EphemerisFilesPanel.Title = 'Ephemeris Files';
            app.GridLayout21 = uigridlayout(app.EphemerisFilesPanel);
            app.getsp3 = uibutton(app.GridLayout21, 'push');
            app.getsp3.ButtonPushedFcn = createCallbackFcn(app, @getsp3ButtonPushed2, true);
            app.getsp3.Icon = fullfile(pathToMLAPP, 'icon', '文件下载.png');
            app.getsp3.Text = 'Get ephemeris files';
            app.Pathofsp3Label = uilabel(app.GridLayout21);
            app.Pathofsp3Label.Text = 'Path of sp3: ';
            app.sp3 = uieditfield(app.GridLayout21, 'text');
            app.sp3.Value = 'E:\桌面\GNSS_IR水位反演软件\data\sp3';
            app.path_sp3 = uibutton(app.GridLayout21, 'push');
            app.path_sp3.ButtonPushedFcn = createCallbackFcn(app, @path_sp3ButtonPushed2, true);
            app.path_sp3.Icon = fullfile(pathToMLAPP, 'icon', '8664907_folder_open_document_icon.png');
            app.path_sp3.Text = 'choose';
            app.sp3filenumber = uilabel(app.GridLayout21);
            app.sp3filenumber.Text = 'Selected: 0 files';
            app.Image3 = uiimage(app.GridLayout21);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'icon', '卫星定位连续运行站点.png');
            app.Label_36 = uilabel(app.GridLayout21);
            app.Label_36.Text = 'Analysis Center:';
            app.sp3_type = uidropdown(app.GridLayout21);
            app.sp3_type.Items = {'com', 'igs', 'COD', 'GFZ'};
            app.sp3_type.Value = 'GFZ';
            app.Label_37 = uilabel(app.GridLayout21);
            app.Label_37.Text = 'Ephemeris Files List';
            app.sp3filename = uitextarea(app.GridLayout21);
            app.TimsPanel = uipanel(app.GridLayout18);
            app.TimsPanel.Title = 'Tims';
            app.GridLayout19 = uigridlayout(app.TimsPanel);
            app.StartdateLabel = uilabel(app.GridLayout19);
            app.StartdateLabel.Text = 'Start date';
            app.starttime = uidatepicker(app.GridLayout19);
            app.starttime.Value = datetime([2023 4 10]);
            app.DatePicker_2Label = uilabel(app.GridLayout19);
            app.DatePicker_2Label.Text = 'End date';
            app.endtime = uidatepicker(app.GridLayout19);
            app.endtime.Value = datetime([2023 4 14]);
            app.Image = uiimage(app.GridLayout19);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'icon', '日期.png');
            app.Image_2 = uiimage(app.GridLayout19);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'icon', '日期.png');
            app.Image9 = uiimage(app.GridLayout18);
            app.Image9.ImageSource = fullfile(pathToMLAPP, 'icon', '全球(1).png');
            app.SaveLoadSettingsButton = uibutton(app.GridLayout18, 'push');
            app.SaveLoadSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveLoadSettingsButtonPushed, true);
            app.SaveLoadSettingsButton.Icon = fullfile(pathToMLAPP, 'icon', '保存.png');
            app.SaveLoadSettingsButton.Text = 'Save & Load Settings';
            app.WaterlevelinversionTab = uitab(app.TabGroup11);
            app.WaterlevelinversionTab.Title = 'Water level inversion';
            app.GridLayout = uigridlayout(app.WaterlevelinversionTab);
            app.cmps = uiaxes(app.GridLayout);
            title(app.cmps, 'Combined multipath phase series')
            xlabel(app.cmps, 'sin(e)')
            ylabel(app.cmps, 'Phase error(mm)')
            zlabel(app.cmps, 'Z')
            app.psd = uiaxes(app.GridLayout);
            title(app.psd, 'Power Spectral Density')
            xlabel(app.psd, 'Frequency (mm^{-1})')
            ylabel(app.psd, 'Power Spectral Density (rad^2/mm^{-1})')
            zlabel(app.psd, 'Z')
            app.Panel = uipanel(app.GridLayout);
            app.Panel.Title = 'Combination frequency selection (make sure the band system is consistent!)';
            app.GridLayout2 = uigridlayout(app.Panel);
            app.SystemLabel = uilabel(app.GridLayout2);
            app.SystemLabel.Text = 'System';
            app.s1 = uidropdown(app.GridLayout2);
            app.s1.Items = {'GPS', 'GLONASS', 'GALILEO', 'BDS'};
            app.s1.DropDownOpeningFcn = createCallbackFcn(app, @s1DropDownOpening, true);
            app.s1.ValueChangedFcn = createCallbackFcn(app, @s1ValueChanged, true);
            app.s1.Value = 'GPS';
            app.Band1Label = uilabel(app.GridLayout2);
            app.Band1Label.Text = 'Band 1:';
            app.FrequencyLabel = uilabel(app.GridLayout2);
            app.FrequencyLabel.Text = 'Frequency';
            app.p1 = uidropdown(app.GridLayout2);
            app.p1.Items = {'C1C'};
            app.p1.Value = 'C1C';
            app.Band2Label = uilabel(app.GridLayout2);
            app.Band2Label.Text = 'Band 2:';
            app.DropDown_9Label = uilabel(app.GridLayout2);
            app.DropDown_9Label.Text = 'System';
            app.s2 = uidropdown(app.GridLayout2);
            app.s2.Items = {'GPS', 'GLONASS', 'GALILEO', 'BDS'};
            app.s2.ValueChangedFcn = createCallbackFcn(app, @s2ValueChanged, true);
            app.s2.Value = 'GPS';
            app.DropDown_12Label = uilabel(app.GridLayout2);
            app.DropDown_12Label.Text = 'Frequency';
            app.p2 = uidropdown(app.GridLayout2);
            app.p2.Items = {'C2W'};
            app.p2.Value = 'C2W';
            app.Band3Label = uilabel(app.GridLayout2);
            app.Band3Label.Text = 'Band 3:';
            app.SystemLabel_2 = uilabel(app.GridLayout2);
            app.SystemLabel_2.Text = 'System';
            app.s3 = uidropdown(app.GridLayout2);
            app.s3.Items = {'GPS', 'GLONASS', 'GALILEO', 'BDS'};
            app.s3.ValueChangedFcn = createCallbackFcn(app, @s3ValueChanged, true);
            app.s3.Value = 'GPS';
            app.DropDown_13Label = uilabel(app.GridLayout2);
            app.DropDown_13Label.Text = 'Frequency';
            app.p3 = uidropdown(app.GridLayout2);
            app.p3.Items = {'C5Q'};
            app.p3.Value = 'C5Q';
            app.car_tab = uitable(app.GridLayout);
            app.car_tab.ColumnName = {'Time'; 'System'; 'BAND'; 'PRN'; 'ROC'; 'MIN_elv'; 'MAX_elv'; 'MEAN_azi'; 'RH'; 'trop_c'};
            app.car_tab.RowName = {};
            app.PseudorangeextractionButton = uibutton(app.GridLayout, 'state');
            app.PseudorangeextractionButton.ValueChangedFcn = createCallbackFcn(app, @PseudorangeextractionButtonValueChanged, true);
            app.PseudorangeextractionButton.Icon = fullfile(pathToMLAPP, 'icon', '01-信息提取.png');
            app.PseudorangeextractionButton.Text = 'Pseudorange extraction';
            app.chooseButton = uibutton(app.GridLayout, 'push');
            app.chooseButton.ButtonPushedFcn = createCallbackFcn(app, @chooseButtonPushed, true);
            app.chooseButton.Icon = fullfile(pathToMLAPP, 'icon', '8664907_folder_open_document_icon.png');
            app.chooseButton.Text = 'choose';
            app.PseudorangepathLabel = uilabel(app.GridLayout);
            app.PseudorangepathLabel.Text = 'Pseudorange path';
            app.path_cpi = uieditfield(app.GridLayout, 'text');
            app.path_cpi.Value = 'E:\桌面\GNSS_IR水位反演软件\results\carrier_phase_information';
            app.chooseButton_2 = uibutton(app.GridLayout, 'push');
            app.chooseButton_2.ButtonPushedFcn = createCallbackFcn(app, @chooseButton_2Pushed, true);
            app.chooseButton_2.Icon = fullfile(pathToMLAPP, 'icon', '8664907_folder_open_document_icon.png');
            app.chooseButton_2.Text = 'choose';
            app.FinalfilePathLabel = uilabel(app.GridLayout);
            app.FinalfilePathLabel.Text = 'Final file Path';
            app.path_cpi_m = uieditfield(app.GridLayout, 'text');
            app.path_cpi_m.Value = 'E:\桌面\GNSS_IR水位反演软件\results\carrier_phase_inversion_results';
            app.EditField_3Label = uilabel(app.GridLayout);
            app.EditField_3Label.Text = 'Antenna height(m)';
            app.antenna_height = uieditfield(app.GridLayout, 'numeric');
            app.antenna_height.Value = 12;
            app.RangemLabel = uilabel(app.GridLayout);
            app.RangemLabel.Text = 'Range (m)';
            app.rf_range = uieditfield(app.GridLayout, 'numeric');
            app.rf_range.Value = 1.5;
            app.Label_4 = uilabel(app.GridLayout);
            app.Label_4.Text = 'Combination type';
            app.combination_type = uidropdown(app.GridLayout);
            app.combination_type.Items = {'Dual frequency combination', 'Three-frequency combination'};
            app.combination_type.ValueChangedFcn = createCallbackFcn(app, @combination_typeValueChanged, true);
            app.combination_type.Value = 'Dual frequency combination';
            app.InverseButton = uibutton(app.GridLayout, 'state');
            app.InverseButton.ValueChangedFcn = createCallbackFcn(app, @InverseButtonValueChanged, true);
            app.InverseButton.Icon = fullfile(pathToMLAPP, 'icon', '卫星反演.png');
            app.InverseButton.Text = 'Inverse';
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = 'Plot';
            app.ispic = uiswitch(app.GridLayout, 'slider');
            app.ispic.Items = {'No', 'Yes'};
            app.ispic.Value = 'No';
            app.TidallevelverificationTab = uitab(app.TabGroup11);
            app.TidallevelverificationTab.Title = 'Tidal level verification';
            app.GridLayout22 = uigridlayout(app.TidallevelverificationTab);
            app.results = uiaxes(app.GridLayout22);
            title(app.results, 'Inversion Result')
            xlabel(app.results, 'Time')
            ylabel(app.results, 'Water Level / m')
            zlabel(app.results, 'Z')
            app.chooseButton_3 = uibutton(app.GridLayout22, 'push');
            app.chooseButton_3.ButtonPushedFcn = createCallbackFcn(app, @chooseButton_3Pushed, true);
            app.chooseButton_3.Icon = fullfile(pathToMLAPP, 'icon', '8664907_folder_open_document_icon.png');
            app.chooseButton_3.Text = 'choose';
            app.result_scatter = uibutton(app.GridLayout22, 'state');
            app.result_scatter.ValueChangedFcn = createCallbackFcn(app, @result_scatterValueChanged, true);
            app.result_scatter.Icon = fullfile(pathToMLAPP, 'icon', '绘制设置.png');
            app.result_scatter.Text = 'Plot & Save';
            app.Label_11 = uilabel(app.GridLayout22);
            app.Label_11.Text = 'Tide data';
            app.tide_path = uieditfield(app.GridLayout22, 'text');
            app.tide_path.Value = 'C:\Users\24391\OneDrive - zx2t0\桌面\GNSS_IR水位反演软件\tide_data.mat';
            app.cor = uicheckbox(app.GridLayout22);
            app.cor.Text = 'Correlation scatter plot';
            app.DH = uicheckbox(app.GridLayout22);
            app.DH.Text = 'Residual histogram';
            app.DrawingoptionsLabel = uilabel(app.GridLayout22);
            app.DrawingoptionsLabel.Text = 'Drawing options';
            app.PseudorangecombinationinversionLabel = uilabel(app.GridLayout23);
            app.PseudorangecombinationinversionLabel.Text = 'Pseudorange combination inversion';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Inversion_based_on_pseudorange_information

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end