classdef fresnel_reflection_region < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        kml_path                      matlab.ui.control.EditField
        PathkmlLabel                  matlab.ui.control.Label
        plot                          matlab.ui.control.Button
        chooseButton                  matlab.ui.control.Button
        fresnel_plot                  matlab.ui.container.Panel
        FresnelreflectionregionLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: chooseButton
        function chooseButtonPushed(app, event)
            dname = uigetdir(pwd);
            app.kml_path.Value = dname;
            return
        end

        % Button pushed function: plot
        function plotButtonPushed(app, event)
            fig = app.UIFigure;
            fresnel_path = app.kml_path.Value;
            delete(app.fresnel_plot.Children)
            h = geoaxes(app.fresnel_plot);
            if isfolder(fresnel_path)
                files = dir(fullfile(fresnel_path, '*'));
                hold(h,'on')
                if length(files) > 250
                    uialert(fig,'Too many files.',"Warning", "Icon","warning");
                    return
                end

                for i = 1:length(files)
                    if ~files(i).isdir
                        file_name = fullfile(fresnel_path, files(i).name);
                        s = kml2struct(file_name);
                        
                        geoplot(h,s.Lat,s.Lon,'r');
                    end
                end
                geobasemap(h,"satellite")
                hold(h,'off')
            else
                message = "choose Wrong!";
                uialert(fig,message,"Warning", "Icon","warning");
                return
            end

            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.FresnelreflectionregionLabel = uilabel(app.GridLayout);
            app.FresnelreflectionregionLabel.Text = 'Fresnel reflection region';
            app.fresnel_plot = uipanel(app.GridLayout);
            app.fresnel_plot.Title = 'Region';
            app.chooseButton = uibutton(app.GridLayout, 'push');
            app.chooseButton.ButtonPushedFcn = createCallbackFcn(app, @chooseButtonPushed, true);
            app.chooseButton.Icon = fullfile(pathToMLAPP, 'icon', '8664907_folder_open_document_icon.png');
            app.chooseButton.Text = 'choose';
            app.plot = uibutton(app.GridLayout, 'push');
            app.plot.ButtonPushedFcn = createCallbackFcn(app, @plotButtonPushed, true);
            app.plot.Icon = fullfile(pathToMLAPP, 'icon', '绘制宗地.png');
            app.plot.Text = 'Plot';
            app.PathkmlLabel = uilabel(app.GridLayout);
            app.PathkmlLabel.Text = 'Path(.kml)';
            app.kml_path = uieditfield(app.GridLayout, 'text');
            app.kml_path.Value = 'E:\桌面\GNSS_IR水位反演软件\results\fresnel_kml';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fresnel_reflection_region

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end